classdef Robot_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        EditField_yawlink3    matlab.ui.control.EditField
        EditField_pitchlink3  matlab.ui.control.EditField
        EditField_rolllink3   matlab.ui.control.EditField
        EditField_zlink3      matlab.ui.control.EditField
        EditField_ylink3      matlab.ui.control.EditField
        EditField_xlink3      matlab.ui.control.EditField
        EditField_yawlink2    matlab.ui.control.EditField
        EditField_pitchlink2  matlab.ui.control.EditField
        EditField_rolllink2   matlab.ui.control.EditField
        EditField_zlink2      matlab.ui.control.EditField
        EditField_ylink2      matlab.ui.control.EditField
        EditField_xlink2      matlab.ui.control.EditField
        Link3Label            matlab.ui.control.Label
        Link2Label            matlab.ui.control.Label
        Link1Label            matlab.ui.control.Label
        EditField_yawlink1    matlab.ui.control.EditField
        EditField_pitchlink1  matlab.ui.control.EditField
        EditField_rolllink1   matlab.ui.control.EditField
        EditField_zlink1      matlab.ui.control.EditField
        EditField_ylink1      matlab.ui.control.EditField
        EditField_xlink1      matlab.ui.control.EditField
        rollLabel             matlab.ui.control.Label
        pitchLabel            matlab.ui.control.Label
        yawLabel              matlab.ui.control.Label
        zLabel                matlab.ui.control.Label
        yLabel                matlab.ui.control.Label
        xLabel                matlab.ui.control.Label
        AngleasdegreeLabel    matlab.ui.control.Label
        Theta1EditField       matlab.ui.control.EditField
        Theta1EditFieldLabel  matlab.ui.control.Label
        Theta2EditField       matlab.ui.control.EditField
        Theta2EditFieldLabel  matlab.ui.control.Label
        Theta3EditField       matlab.ui.control.EditField
        Theta3EditFieldLabel  matlab.ui.control.Label
        ForwardButton         matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            %% Get data from text box (This is ARTICULATED ARM. so the input is 3 thetas)
            theta1=str2double(app.Theta1EditField.Value)*pi/180;
            theta2=str2double(app.Theta2EditField.Value)*pi/180;
            theta3=str2double(app.Theta3EditField.Value)*pi/180;
            
            %% declare links constain
            d1=3;
            d2=0;
            d3=0;
            a1=0;
            a2=1;
            a3=1;
            alpha1=pi/2;
            alpha2=0;
            alpha3=0;
            
            %% create links matrix(Denavit-Hartenberg matrix)
            PO=[0;0;0;1];
            [A0_1,R0_1]=Add_Link(theta1,d1,a1,alpha1);
            [A1_2,R1_2]=Add_Link(theta2,d2,a2,alpha2);
            [A2_3,R2_3]=Add_Link(theta3,d3,a3,alpha3);
            
            %% Calculate joints, end-effector
            P1=A0_1*PO;
            P2=A0_1*A1_2*PO;
            P3=A0_1*A1_2*A2_3*PO;

            V=inv([3 0 0 0;0 3 0 0;0 0 3 0;0 0 0 1]);
            V1=R0_1*V;
            V2=R1_2*V1;
            V3=R2_3*V2;

            %% Show end-effector point
            app.EditField_xlink1.Value=num2str(round(P1(1,1)));
            app.EditField_ylink1.Value=num2str(round(P1(2,1)));
            app.EditField_zlink1.Value=num2str(round(P1(3,1)));
            app.EditField_xlink2.Value=num2str(round(P2(1,1)));
            app.EditField_ylink2.Value=num2str(round(P2(2,1)));
            app.EditField_zlink2.Value=num2str(round(P2(3,1)));
            app.EditField_xlink3.Value=num2str(round(P3(1,1)));
            app.EditField_ylink3.Value=num2str(round(P3(2,1)));
            app.EditField_zlink3.Value=num2str(round(P3(3,1)));

            %% Show robot on UI
            plot3(app.UIAxes,0,0,0,'or');
            xlim(app.UIAxes,[-3,3])
            ylim(app.UIAxes,[-3,3])
            zlim(app.UIAxes,[0,6])
            xlabel(app.UIAxes,'x')
            ylabel(app.UIAxes,'y')
            zlabel(app.UIAxes,'z')
            
            hold(app.UIAxes,'on')
            grid(app.UIAxes,'on')

            quiver3(app.UIAxes,PO(1,1),PO(2,1),PO(3,1),V(1,1),V(2,1),V(3,1),'LineWidth',2,'Color','black')
            quiver3(app.UIAxes,PO(1,1),PO(2,1),PO(3,1),V(1,2),V(2,2),V(3,2),'LineWidth',2,'Color','cyan')
            quiver3(app.UIAxes,PO(1,1),PO(2,1),PO(3,1),V(1,3),V(2,3),V(3,3),'LineWidth',2,'Color','green')

            quiver3(app.UIAxes,P1(1,1),P1(2,1),P1(3,1),V1(1,1),V1(2,1),V1(3,1),'LineWidth',2,'Color','black')
            quiver3(app.UIAxes,P1(1,1),P1(2,1),P1(3,1),V1(1,2),V1(2,2),V1(3,2),'LineWidth',2,'Color','cyan')
            quiver3(app.UIAxes,P1(1,1),P1(2,1),P1(3,1),V1(1,3),V1(2,3),V1(3,3),'LineWidth',2,'Color','green')

            quiver3(app.UIAxes,P2(1,1),P2(2,1),P2(3,1),V2(1,1),V2(2,1),V2(3,1),'LineWidth',2,'Color','black')
            quiver3(app.UIAxes,P2(1,1),P2(2,1),P2(3,1),V2(1,2),V2(2,2),V2(3,2),'LineWidth',2,'Color','cyan')
            quiver3(app.UIAxes,P2(1,1),P2(2,1),P2(3,1),V2(1,3),V2(2,3),V2(3,3),'LineWidth',2,'Color','green')

            quiver3(app.UIAxes,P3(1,1),P3(2,1),P3(3,1),V3(1,1),V3(2,1),V3(3,1),'LineWidth',2,'Color','black')
            quiver3(app.UIAxes,P3(1,1),P3(2,1),P3(3,1),V3(1,2),V3(2,2),V3(3,2),'LineWidth',2,'Color','cyan')
            quiver3(app.UIAxes,P3(1,1),P3(2,1),P3(3,1),V3(1,3),V3(2,3),V3(3,3),'LineWidth',2,'Color','green')

            line(app.UIAxes,[0,P1(1,1)],[0,P1(2,1)],[0,P1(3,1)],'color','red');
            plot3(app.UIAxes,P1(1,1),P1(2,1),P1(3,1),'or');
            line(app.UIAxes,[P1(1,1),P2(1,1)],[P1(2,1),P2(2,1)],[P1(3,1),P2(3,1)],'color','blue');
            plot3(app.UIAxes,P2(1,1),P2(2,1),P2(3,1),'or');
            line(app.UIAxes,[P2(1,1),P3(1,1)],[P2(2,1),P3(2,1)],[P2(3,1),P3(3,1)],'color','green');
            plot3(app.UIAxes,P3(1,1),P3(2,1),P3(3,1),'or');
            hold(app.UIAxes,'off')


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Robot visualize')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ForwardButton = uibutton(app.UIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Tag = 'btn_forward';
            app.ForwardButton.Text = 'Forward';
            app.Theta3EditFieldLabel = uilabel(app.UIFigure);
            app.Theta3EditFieldLabel.Text = 'Theta 3';
            app.Theta3EditField = uieditfield(app.UIFigure, 'text');
            app.Theta3EditField.Tag = 'Theta_3_txt';
            app.Theta2EditFieldLabel = uilabel(app.UIFigure);
            app.Theta2EditFieldLabel.Text = 'Theta 2';
            app.Theta2EditField = uieditfield(app.UIFigure, 'text');
            app.Theta2EditField.Tag = 'Theta_2_txt';
            app.Theta1EditFieldLabel = uilabel(app.UIFigure);
            app.Theta1EditFieldLabel.Text = 'Theta 1';
            app.Theta1EditField = uieditfield(app.UIFigure, 'text');
            app.Theta1EditField.Tag = 'Theta_1_txt';
            app.AngleasdegreeLabel = uilabel(app.UIFigure);
            app.AngleasdegreeLabel.Text = 'Angle as degree';
            app.xLabel = uilabel(app.UIFigure);
            app.xLabel.Text = 'x';
            app.yLabel = uilabel(app.UIFigure);
            app.yLabel.Text = 'y';
            app.zLabel = uilabel(app.UIFigure);
            app.zLabel.Text = 'z';
            app.yawLabel = uilabel(app.UIFigure);
            app.yawLabel.Text = 'yaw';
            app.pitchLabel = uilabel(app.UIFigure);
            app.pitchLabel.Text = 'pitch';
            app.rollLabel = uilabel(app.UIFigure);
            app.rollLabel.Text = 'roll';
            app.EditField_xlink1 = uieditfield(app.UIFigure, 'text');
            app.EditField_ylink1 = uieditfield(app.UIFigure, 'text');
            app.EditField_zlink1 = uieditfield(app.UIFigure, 'text');
            app.EditField_rolllink1 = uieditfield(app.UIFigure, 'text');
            app.EditField_pitchlink1 = uieditfield(app.UIFigure, 'text');
            app.EditField_yawlink1 = uieditfield(app.UIFigure, 'text');
            app.Link1Label = uilabel(app.UIFigure);
            app.Link1Label.Text = 'Link 1';
            app.Link2Label = uilabel(app.UIFigure);
            app.Link2Label.Text = 'Link 2';
            app.Link3Label = uilabel(app.UIFigure);
            app.Link3Label.Text = 'Link 3';
            app.EditField_xlink2 = uieditfield(app.UIFigure, 'text');
            app.EditField_ylink2 = uieditfield(app.UIFigure, 'text');
            app.EditField_zlink2 = uieditfield(app.UIFigure, 'text');
            app.EditField_rolllink2 = uieditfield(app.UIFigure, 'text');
            app.EditField_pitchlink2 = uieditfield(app.UIFigure, 'text');
            app.EditField_yawlink2 = uieditfield(app.UIFigure, 'text');
            app.EditField_xlink3 = uieditfield(app.UIFigure, 'text');
            app.EditField_ylink3 = uieditfield(app.UIFigure, 'text');
            app.EditField_zlink3 = uieditfield(app.UIFigure, 'text');
            app.EditField_rolllink3 = uieditfield(app.UIFigure, 'text');
            app.EditField_pitchlink3 = uieditfield(app.UIFigure, 'text');
            app.EditField_yawlink3 = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Robot_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end